classdef PRMIA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AccountMenu                    matlab.ui.container.Menu
        Menu_2                         matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
        SettingMenu                    matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        PerformanceAnalysisTab         matlab.ui.container.Tab
        PositionsPanel                 matlab.ui.container.Panel
        PositionTable                  matlab.ui.control.Table
        PerformancePanel               matlab.ui.container.Panel
        TabGroup2                      matlab.ui.container.TabGroup
        CurrencyPriceTab               matlab.ui.container.Tab
        UIAxes5                        matlab.ui.control.UIAxes
        VolatilityTab                  matlab.ui.container.Tab
        UIAxes6                        matlab.ui.control.UIAxes
        PNLTab                         matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        ReinvestCheckBox               matlab.ui.control.CheckBox
        TransactionCostCheckBox        matlab.ui.control.CheckBox
        ApplyButton                    matlab.ui.control.Button
        StartDateDatePicker_2          matlab.ui.control.DatePicker
        StartDateDatePicker_2Label     matlab.ui.control.Label
        EndDateDatePicker_2            matlab.ui.control.DatePicker
        EndDateDatePicker_2Label       matlab.ui.control.Label
        StatisticsTextArea             matlab.ui.control.TextArea
        StatisticsTextAreaLabel        matlab.ui.control.Label
        DisplayScaleDropDown           matlab.ui.control.DropDown
        DisplayScaleDropDownLabel      matlab.ui.control.Label
        EnteryourInitalcapitalEditField  matlab.ui.control.NumericEditField
        EnteryourInitalcapitalEditFieldLabel  matlab.ui.control.Label
        FinalCapitalEditFieldLabel     matlab.ui.control.Label
        FinalCapitalEditField          matlab.ui.control.NumericEditField
        LoadPortfolioButton            matlab.ui.control.Button
        ClearButton                    matlab.ui.control.Button
        RiskManagementTab              matlab.ui.container.Tab
        VaRandESBacktestingPanel       matlab.ui.container.Panel
        Lamp                           matlab.ui.control.Lamp
        PlotButton                     matlab.ui.control.Button
        StartDateDatePickerLabel       matlab.ui.control.Label
        StartDateDatePicker            matlab.ui.control.DatePicker
        EndDateDatePickerLabel         matlab.ui.control.Label
        EndDateDatePicker              matlab.ui.control.DatePicker
        VaRLevelEditFieldLabel         matlab.ui.control.Label
        VaRLevelEditField              matlab.ui.control.NumericEditField
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes4_2                      matlab.ui.control.UIAxes
        RiskMatrixPanel                matlab.ui.container.Panel
        UpdateButton_2                 matlab.ui.control.Button
        UITable3                       matlab.ui.control.Table
        UnderlyingScaleEditFieldLabel  matlab.ui.control.Label
        UnderlyingScaleEditField       matlab.ui.control.EditField
        VolScaleEditFieldLabel         matlab.ui.control.Label
        VolScaleEditField              matlab.ui.control.EditField
        RiskMetricsListBoxLabel        matlab.ui.control.Label
        RiskMetricsListBox             matlab.ui.control.ListBox
    end

    properties (Access = private)
        position table
        vol_table table
        currency_table table
        start_date datetime
        end_date datetime
        start_date2 datetime
        end_date2 datetime
        returns 
        Date 
        portfolio_name
        hlegend
        plot_num  = 0
    end
    
    methods (Access = private)
        
        function [var, es] = hHistoricalVaRES(~, returns, level)  %function for calculation of VaR using historical simulation method
            returns = sort(returns);
            index = uint8((1 - level) * length(returns));
            var = returns(index);
            es = mean(returns(1:index));
        end
        
        %Calculate Greeks
        function [fairValue, greeks] = calcGreeks(~, spot, strike, rate, yield, volatility, maturity, putCallInd, annualFactor)
        % calcGreeks - Calculate Greeks (Black/Scholes, Vanilla European Option, Closed Form)
        %
        % calcGreeks computes and reports the fair price value and numerous Greek values
        % for vanilla European options, using the Black-Sholes-Merton model, optimized
        % for performance. No toolbox is required - only basic Matlab.
        %
        % Any input parameter can be vectorized (examples below), but only one parameter
        % can be vectorized.
        %
        % calcGreeks is used by the IQFeed-Matlab connector (IQML - https://undocumentedmatlab.com/IQML)
        %
        % Syntax:
        %    [fairValue, greeks] = calcGreeks(spot, strike, rate, yield, volatility, maturity, putCallInd, annualFactor)
        %
        % Inputs:
        %    spot         - (mandatory) Underlying asset's spot price
        %    strike       - (mandatory) Derivative contract's strike price
        %    rate         - (default: 0) Domestic risk-free interest rate (%)
        %    yield        - (default: 0) Foreign interest rate (Forex) or dividend yield (stock)
        %    volatility   - (default: 0.3) Historic volatility of the underlying asset's price
        %    maturity     - (default: 1.0) Number of years until derivative contract expires
        %    putCallInd   - (default: 'Call') Either 1 (Call), -1 (Put), or [1,-1] (both)
        %                   or as strings: "Call", 'put', 'cp', {'Call',"put"} etc.
        %    annualFactor - (default: 1) Used to de-annualize Theta, Charm, Veta, Color
        %                   1: report annualized values; 365: report 1-day estimates
        %                   Typical values: 1, 365, 252
            if nargin < 8, annualFactor = 1;  end  % compatibility with Matlab's Financial Toolbox, NAG, Maple
            if nargin < 7, putCallInd   = 1;  end  % default: Call
            if nargin < 6, maturity     = 1;  end  % default: 1 year
            if nargin < 5, volatility   = 30; end  % default: 30%
            if nargin < 4, yield        = 0;  end  % default: 0%
            if nargin < 3, rate         = 0;  end  % default: 0%
            if nargin < 2, error('At least one spot price and one strike price must be specified!'); end
            % Normalize % values to fractions:
            rate  = rate/100;
            yield = yield/100;
            volatility = volatility/100;
            % Normalize putCallInd that can be specified in many different formats
            try %#ok if isa(putCallInd,'string')
                putCallInd = controllib.internal.util.hString2Char(putCallInd);
            end
            if iscellstr(putCallInd)
                putCallInd = cellfun(@(c)c(1),putCallInd);
            end
            if ischar(putCallInd)
                putCallInd = unique(lower(regexprep(putCallInd,'[^PpCc]','')),'stable');
                pcVal = [];
                for idx = 1 : length(putCallInd)
                    if putCallInd(idx)=='c'
                        pcVal(end+1) = +1; %#ok<AGROW>
                    elseif putCallInd(idx)=='p'
                        pcVal(end+1) = -1; %#ok<AGROW>
                    end
                end
                if isempty(pcVal), error('Either put or call or both must be specified!'); end
                putCallInd = pcVal;
            elseif iscell(putCallInd)
                putCallInd = [putCallInd{:}];
            end
            % Calculate basic mathematical values that are used below
            r = rate;
            q = yield;
            b = r-q;  % Carry rate: https://www.nag.co.uk/numeric/MB/manual64_25_1/pdf/S/s30ab.pdf
            sqrt_maturity = sqrt(maturity);
            f = volatility .* sqrt_maturity;
            sqr_sigma = volatility .* volatility;
            sqr_sigma_2 = 0.5 * sqr_sigma;
            d1 = (log(spot./strike) + (b + sqr_sigma_2) .* maturity) ./ f;
            d2 = d1 - f; %=(log(spot/strike) + (r-q-sqr_sigma_2) * maturity) / f;
            eqm = exp(-q .* maturity);
            erm = exp(-r .* maturity);
            npd1 = 1/sqrt(2*pi) * exp(-d1.^2/2); %=normpdf(d1);
            epd1 = eqm .* npd1;
            sepd1 = spot .* epd1;
            sqrt2 = sqrt(2);
            ncd1 = 0.5*erfc(-putCallInd.*d1/sqrt2); %=0.5*erf(putCallInd*d1/sqrt2)+0.5; %=normcdf(putCallInd*d1);
            ncd2 = 0.5*erfc(-putCallInd.*d2/sqrt2); %=0.5*erf(putCallInd*d2/sqrt2)+0.5; %=normcdf(putCallInd*d2);
            ecd1 = eqm .* ncd1;
            ecd2 = erm .* ncd2;
            secd1 = spot   .* ecd1;
            secd2 = strike .* ecd2;
            % Compute and report the fair value (bail-out immediately if greeks are not requested)
            fairValue = putCallInd .* (secd1 - secd2);
            if nargout < 2,  return,  end
            % 1st-order Greeks
            greeks.delta = putCallInd .* ecd1;
            greeks.vega  = sepd1 .* sqrt_maturity; %*0.01
            greeks.theta = (-sepd1 .* sqr_sigma_2 ./ f + putCallInd.*(q.*secd1 - r.*secd2)) / annualFactor;
            greeks.rho   = putCallInd .* maturity .* secd2; %*0.01
            greeks.crho  = greeks.rho .* secd1 ./ secd2;  %https://www.nag.co.uk/numeric/MB/manual64_25_1/pdf/S/s30ab.pdf
            greeks.omega = greeks.delta .* spot ./ fairValue;
            greeks.lambda = greeks.omega; %synonym
            % 2nd-order Greeks
            d12 = d1 .* d2;
            greeks.gamma = epd1 ./ spot ./ f;
            greeks.vanna = -epd1 .* d2 ./ volatility;  % =vega/spot*(1-d1/f);
            greeks.charm = q .* greeks.delta - epd1 .* (b./f - 0.5*d2./maturity) / annualFactor;
            greeks.vomma = sepd1 .* f .* d12 / sqr_sigma;  % AKA volga, vega convexity
            greeks.volga = greeks.vomma; %synonym
            greeks.veta  = -sepd1 .* sqrt_maturity .* (q + b.*d1./f - 0.5*(1+d12)./maturity) / annualFactor; %*0.01
            % 3rd-order Greeks
            greeks.speed  = -greeks.gamma ./ spot .* (d1./f + 1);
            greeks.zomma  =  greeks.gamma .* (d12-1) ./ volatility;
            greeks.color  = -greeks.gamma .* (q + 1./(2*maturity) + (b./f - d2./(2*maturity)).*d1) / annualFactor;
            greeks.ultima = -greeks.vega ./ sqr_sigma .* (d12.*(1-d12) + d1.*d1 + d2.*d2);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            data = readtable('position.csv','PreserveVariableNames',true); %read posiiton data from csv file. it should be real-time data in reality 
            app.PositionTable.Data = data;
            app.PositionTable.ColumnName = data.Properties.VariableNames;    
            data = table2array(data(:,2:9));
            [row, col] = find(data(:,8) > 0);
            col = col + 8;
            s = uistyle('BackgroundColor','green'); %mark positive P&L with green color
            addStyle(app.PositionTable,s,'cell',[row,col]);
            [row, col] = find(data(:,8) < 0);
            col = col + 8;
            s = uistyle('BackgroundColor','red');
            addStyle(app.PositionTable,s,'cell',[row,col]);  %mark negative P&L with red color
           
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            cla(app.UIAxes4,'reset'); % read uploaded portfolio data
            cla(app.UIAxes4_2,'reset');
            Returns = app.returns;
            DateReturns = app.Date;
            TestWindowStart = 1;
            TestWindowEnd = length(DateReturns);
            
            
            if app.start_date2 ~= NaT && app.end_date2 ~= NaT
                TestWindowStart = find(DateReturns == app.StartDateDatePicker.Value);
                TestWindowEnd = find(DateReturns==app.EndDateDatePicker.Value);
            end
            
            EstimationWindowSize = 50;
            TestWindow = TestWindowStart+EstimationWindowSize:TestWindowEnd;
            
            
            DatesTest = DateReturns(TestWindow);
            ReturnsTest = Returns(TestWindow);
            
            VaRLevel = 0.9; % default VaR level
            if app.VaRLevelEditField.Value ~= 0
                VaRLevel = app.VaRLevelEditField.Value; % VaR level can be modified by user
            end
            
            VaR_Hist = zeros(length(TestWindow) ,1);
            ES_Hist = zeros(length(TestWindow),1);
            
            for t = TestWindow
               
               i = t - TestWindowStart + 1;
               EstimationWindow = t-EstimationWindowSize:t-1;
               
               [VaR_Hist(i - EstimationWindowSize),ES_Hist(i - EstimationWindowSize)]...
                   = hHistoricalVaRES(app, Returns(EstimationWindow),VaRLevel);
            end
            % calculate VaR using historical simulation method and
            % backtesting. 
            date_ = datenum(DatesTest);
            plot(app.UIAxes4, date_,[ReturnsTest,-VaR_Hist,-ES_Hist])
            datetick(app.UIAxes4, 'x', 'yyyy-mm', 'keepticks');
            legend(app.UIAxes4,{'Returns','VaR','ES'},'Location','southeast')
            title(app.UIAxes4,'Historical VaR and ES')
            ylabel(app.UIAxes4, 'Level')

%------------------------------------------------------------------------------------------------------            
            pVaR = 1 - VaRLevel;
            Zscore   = norminv(pVaR);
            Normal95 = zeros(length(TestWindow),1);
            
            for t = TestWindow
                i = t - TestWindowStart + 1;
                EstimationWindow = t-EstimationWindowSize:t-1;
                Sigma = std(Returns(EstimationWindow));
                Normal95(i - EstimationWindowSize) = -Zscore(1)*Sigma;
            end
            %calculate VaR using normal distribution method
            Lambda = 0.94;
            Sigma2     = zeros(length(Returns),1);
            Sigma2(1)  = Returns(1)^2;
            
            for i = 2 : (TestWindowStart-1)
                Sigma2(i) = (1-Lambda) * Returns(i-1)^2 + Lambda * Sigma2(i-1);
            end
            Zscore = norminv(pVaR);
            EWMA95 = zeros(length(TestWindow),1);
            
            for t = TestWindow
                k     = t - TestWindowStart + 1;
                Sigma2(t) = (1-Lambda) * Returns(t-1)^2 + Lambda * Sigma2(t-1);
                Sigma = sqrt(Sigma2(t));
                EWMA95(k - EstimationWindowSize) = -Zscore(1)*Sigma;
            end
            %calculate VaR using EWMA method and backtesting
            ReturnsTest = Returns(TestWindow);
            DatesTest   = DateReturns(TestWindow);
            date_ = datenum(DatesTest);
            plot(app.UIAxes4_2, date_,[ReturnsTest Normal95 -VaR_Hist EWMA95])
            datetick(app.UIAxes4_2, 'x', 'yyyy-mm', 'keepticks');
            ylabel(app.UIAxes4_2, 'VaR')
            xlabel(app.UIAxes4_2, 'Date')
            legend(app.UIAxes4_2, {'Returns','Normal','Historical','EWMA'},'Location','Northeast')
            title(app.UIAxes4_2, sprintf('Comparison of returns and VaR at %.3f for different models', VaRLevel))
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            price = readtable('OptionPrice.csv','PreserveVariableNames',true); %read option price from csv file for portfolio P&L calculation
            difference = diff(price{:,2:end});
            price{:,2:end}(ismissing(price{:,2:end})) = 0;
            daily_capital = sum(price{:,2:end} .* app.position{:,2:end},2);
            price{1:end-1,2:end} = difference;
            price{:,2:end}(ismissing(price{:,2:end})) = 0;
            price{end,2:end} = 0;
            pnl = price{:,2:end} .* app.position{:,2:end};
           % implied volatility and history currency movement can be
           % plotted wihout loading portfolio but p&L plot requires
           % portfolio loading
            if app.TransactionCostCheckBox.Value == 1 % apply transaction costs to P&L calculation
                app.portfolio_name = app.portfolio_name + "Cost";
                pnl(1:end-1,:) = pnl(1:end-1,:) - ...
                    0.02*abs(diff(app.position{:,2:end}).*price{1:end-1,2:end});
            end
            daily_pnl = sum(pnl,2);
            daily_return = daily_pnl./daily_capital; 
            acc_pnl = 1 + cumsum(daily_return);
            date = datenum(price.Date);
   
            app.returns = daily_return;
            app.Date = price.Date;
            
            if app.start_date ~= NaT && app.end_date ~= NaT   % choose time period
                start_index = find(price.Date == app.start_date);
                end_index = find(price.Date == app.end_date);
                daily_return = daily_return(start_index:end_index);
                date = date(start_index:end_index);
                acc_pnl = 1 + cumsum(daily_return);
            end
            
            if app.ReinvestCheckBox.Value == 1 % apply reinvesting to P&L calculation
                acc_pnl = cumprod(1 + daily_return);
                app.portfolio_name = app.portfolio_name + "Reinvest";
            end       
            % reinvesting and transaction cost can be applied at the same time
            % P&L plot
            plot(app.UIAxes3, date, acc_pnl);  
            hold(app.UIAxes3,'on');
            datetick(app.UIAxes3, 'x', 'yyyy-mm', 'keepticks');
            if app.plot_num == 0
                app.plot_num = 1;
                app.hlegend = legend(app.UIAxes3,sprintf("%s", app.portfolio_name),'Location','northwest');
            else
                app.plot_num = app.plot_num + 1;
                app.hlegend.String{app.plot_num} = sprintf("%s", app.portfolio_name);
            end
            
            title(app.UIAxes3, 'Portfolio PNL');
            xlabel(app.UIAxes3,'Time');
            ylabel(app.UIAxes3,'PNL');  
            hold(app.UIAxes3, 'on');
                        
            vol_data = app.vol_table;
            cur = app.currency_table; 
            dates = datenum(vol_data.Date);
            EURUSD = vol_data.EURUSD;
            AUDUSD = vol_data.AUDUSD;
            EURUSD2 = cur.EURUSD;
            AUDUSD2 = cur.AUDUSD;
            
            % choose time period
            if app.start_date ~= NaT & app.end_date ~= NaT 
                start_index = find(vol_data.Date == app.start_date);
                end_index = find(vol_data.Date == app.end_date);
                dates = dates(start_index:end_index);
                EURUSD = EURUSD(start_index:end_index);
                AUDUSD = AUDUSD(start_index:end_index);
                EURUSD2 = EURUSD2(start_index:end_index);
                AUDUSD2 = AUDUSD2(start_index:end_index);
                cla(app.UIAxes5,'reset')
                cla(app.UIAxes6,'reset')
            end           
           % implied volatility plot
            plot(app.UIAxes6, dates, EURUSD);
            hold(app.UIAxes6,'on');
            plot(app.UIAxes6, dates, AUDUSD);
            datetick(app.UIAxes6, 'x', 'yyyy-mm', 'keepticks');
            legend(app.UIAxes6,'EURUSD','AUDUSD');
            title(app.UIAxes6, 'Implied Volatility');
            xlabel(app.UIAxes6,'Time')
            ylabel(app.UIAxes6,'Level'); 
            % currency history movement plot
            plot(app.UIAxes5, dates, EURUSD2);
            hold(app.UIAxes5,'on');
            plot(app.UIAxes5, dates, AUDUSD2);
            datetick(app.UIAxes5, 'x', 'yyyy-mm', 'keepticks');
            legend(app.UIAxes5,'EURUSD','AUDUSD');
            title(app.UIAxes5, 'Currency History Movement');
            xlabel(app.UIAxes5,'Time');
            ylabel(app.UIAxes5,'Level');    % these plots can change as shift of time period          
            
            
            % display scale options daily/monthly
            % calcualtion for statistical analysis
            if app.DisplayScaleDropDown.Value == "Daily"
                year_return = 250 * mean(daily_return);
                year_vol = sqrt(250) * std(daily_return);
                infor = year_return / year_vol;
                sharpe = (year_return - 0.01) / year_vol;
                median_pnl = median(daily_return) * app.EnteryourInitalcapitalEditField.Value;
                mean_pnl = mean(daily_return) * app.EnteryourInitalcapitalEditField.Value;
                if app.ReinvestCheckBox.Value == 1
                    drawdown = maxdrawdown(acc_pnl);
                end
                prob = sum(daily_pnl > 0)/length(daily_pnl);
            end
            
            if app.DisplayScaleDropDown.Value == "Monthly"
                year_return = 250 * mean(daily_return);
                year_vol = sqrt(250) * std(daily_return);
                infor = year_return / year_vol;
                sharpe = (year_return - 0.01) / year_vol;
                median_pnl = median(daily_return) * app.EnteryourInitalcapitalEditField.Value / 12;
                mean_pnl = mean(daily_return) * app.EnteryourInitalcapitalEditField.Value / 12;
                if app.ReinvestCheckBox.Value == 1
                    drawdown = maxdrawdown(acc_pnl);
                end
                prob = sum(daily_pnl > 0)/length(daily_pnl);
            end

            
            app.FinalCapitalEditField.Value = acc_pnl(end) * app.EnteryourInitalcapitalEditField.Value;
            % statistical analysis of return,volatility,sharpe ratio,
            % information ratio median and mean of P&L, and probability of
            % positive return. If reinvesting is applied, max dropdown will
            % also be calculated.
            app.StatisticsTextArea.FontSize = 15;
            if app.ReinvestCheckBox.Value == 0
                app.StatisticsTextArea.Value = [sprintf("Yearly Return: %.4f", year_return);
                    sprintf("Yearly Volatility: %.4f", year_vol);
                    sprintf("Information Ratio: %.4f", sharpe);
                    sprintf("Sharpe Ratio: %.2f", infor);
                    sprintf("Median PNL: %.4f", median_pnl);
                    sprintf("Mean PNL:  %.4f", mean_pnl);
                    sprintf("Probability of Positive PNL: %.2f",prob)];
            
            else
                app.StatisticsTextArea.Value = [sprintf("Yearly Return: %.4f", year_return);
                    sprintf("Yearly Volatility: %.4f", year_vol);
                    sprintf("Information Ratio: %.4f", sharpe);
                    sprintf("Sharpe Ratio: %.2f", infor);
                    sprintf("Median PNL: %.4f", median_pnl);
                    sprintf("Mean PNL:  %.4f", mean_pnl);
                    sprintf("Probability of Positive PNL: %.2f",prob);                
                    sprintf("Maximum Dropdown: %.4f", drawdown)];
            end
        end

        % Button pushed function: UpdateButton_2
        function UpdateButton_2Pushed(app, event)
            data = readtable('matrix.csv','PreserveVariableNames',true); % read risk metircs data. it should also be calculated with real tiem data in reality
            if ~isnan(app.UnderlyingScaleEditField.Value)
                und = app.UnderlyingScaleEditField.Value;
                und_array = split(und, ",");
                len = length(und_array);
                und_int = zeros(len,1);
                for index = 1:len
                    und_int(index) = str2num(und_array{index});
                end
                cond = ismember(data.("Underlying Pct"),und_int);
                data = data(cond,:);
            end

            if ~isnan(app.VolScaleEditField.Value)
                und = app.VolScaleEditField.Value;
                und_array = split(und, ",");
                len = length(und_array);
                und_int = zeros(len,1);
                for index = 1:len
                    und_int(index) = str2num(und_array{index});
                end
                cond = ismember(data.("Vol Pct"),und_int);
                data = data(cond,:);
            end
            

            metrics = app.RiskMetricsListBox.Value; % shown metircs can be chosen separately
            cond = ismember(data.Properties.VariableNames, metrics);
            cond(1) = 1;
            cond(2) = 1;
            data = data(:,cond);

            app.UITable3.Data = data;
            app.UITable3.ColumnName = data.Properties.VariableNames;
            if cond(3) == 0
                data = table2array(data(:,1:2));
            else
                data = table2array(data(:,1:3));
            end
            
            [row, col] = find(data > 0);
            s = uistyle('BackgroundColor','green');
            addStyle(app.UITable3,s,'cell',[row,col]);
            [row, col] = find(data < 0);
            s = uistyle('BackgroundColor','red');
            addStyle(app.UITable3,s,'cell',[row,col]);               
        end

        % Button pushed function: LoadPortfolioButton
        function LoadPortfolioButtonPushed(app, event)
            [file_name, file_path] = uigetfile('*.*', 'Please select a file to import'); %read portfolio csv file
            positionS = readtable(convertCharsToStrings(file_path) + "\" +...
                convertCharsToStrings(file_name),'PreserveVariableNames',true);
            
            app.position = positionS; % put portfolio data in features
            file_name = split(convertCharsToStrings(file_name),'.');
            app.portfolio_name = file_name(1);

            cla(app.UIAxes5,'reset')
            cla(app.UIAxes6,'reset')
            vol_data = readtable('currency_impliedvol.csv','PreserveVariableNames',true); % read volatility data
            app.vol_table = vol_data;
            date = datenum(vol_data.Date);
            plot(app.UIAxes6, date, vol_data.EURUSD);
            hold(app.UIAxes6,'on');
            plot(app.UIAxes6, date, vol_data.AUDUSD);
            datetick(app.UIAxes6, 'x', 'yyyy-mm', 'keepticks');
            legend(app.UIAxes6,'EURUSD','AUDUSD');
            title(app.UIAxes6, 'Implied Volatility');
            xlabel(app.UIAxes6,'Time')
            ylabel(app.UIAxes6,'Level');  
            
            currency_data = readtable('Currency.csv','PreserveVariableNames',true); % read currency rate data
            app.currency_table = currency_data;
            date = datenum(currency_data.Date);
            plot(app.UIAxes5, date, currency_data.EURUSD);
            hold(app.UIAxes5,'on');
            plot(app.UIAxes5, date, currency_data.AUDUSD);
            datetick(app.UIAxes5, 'x', 'yyyy-mm', 'keepticks')
            legend(app.UIAxes5,'EURUSD','AUDUSD');
            title(app.UIAxes5, 'Currency History Movement');
            xlabel(app.UIAxes5,'Time')
            ylabel(app.UIAxes5,'Level'); 
        end

        % Value changed function: StartDateDatePicker_2
        function StartDateDatePicker_2ValueChanged(app, event)
            value = app.StartDateDatePicker_2.Value;
            app.start_date = value;
        end

        % Value changed function: EndDateDatePicker_2
        function EndDateDatePicker_2ValueChanged(app, event)
            value = app.EndDateDatePicker_2.Value;
            app.end_date = value;
        end

        % Value changed function: StartDateDatePicker
        function StartDateDatePickerValueChanged(app, event)
            value = app.StartDateDatePicker.Value;
            app.start_date2 = value;
        end

        % Value changed function: EndDateDatePicker
        function EndDateDatePickerValueChanged(app, event)
            value = app.EndDateDatePicker.Value;
            app.end_date2 = value;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes3,'reset'); % clean statistics and plots
            app.StatisticsTextArea.Value = "";
            app.plot_num = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AccountMenu = uimenu(app.UIFigure);
            app.AccountMenu.Text = 'Account';
            app.Menu_2 = uimenu(app.AccountMenu);
            app.Menu_2.Text = 'Menu';
            app.Menu2 = uimenu(app.AccountMenu);
            app.Menu2.Text = 'Menu2';
            app.SettingMenu = uimenu(app.UIFigure);
            app.SettingMenu.Text = 'Setting';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PerformanceAnalysisTab = uitab(app.TabGroup);
            app.PerformanceAnalysisTab.Title = 'Performance Analysis';
            app.PositionsPanel = uipanel(app.PerformanceAnalysisTab);
            app.PositionsPanel.Title = 'Positions';
            app.PositionTable = uitable(app.PositionsPanel);
            app.PositionTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.PositionTable.RowName = {};
            app.PerformancePanel = uipanel(app.PerformanceAnalysisTab);
            app.PerformancePanel.Title = 'Performance ';
            app.TabGroup2 = uitabgroup(app.PerformancePanel);
            app.CurrencyPriceTab = uitab(app.TabGroup2);
            app.CurrencyPriceTab.Title = 'Currency Price';
            app.UIAxes5 = uiaxes(app.CurrencyPriceTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.VolatilityTab = uitab(app.TabGroup2);
            app.VolatilityTab.Title = 'Volatility';
            app.UIAxes6 = uiaxes(app.VolatilityTab);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.PNLTab = uitab(app.TabGroup2);
            app.PNLTab.Title = 'PNL';
            app.UIAxes3 = uiaxes(app.PNLTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.Panel = uipanel(app.PerformancePanel);
            app.ReinvestCheckBox = uicheckbox(app.Panel);
            app.ReinvestCheckBox.Text = 'Reinvest';
            app.TransactionCostCheckBox = uicheckbox(app.Panel);
            app.TransactionCostCheckBox.Text = 'Transaction Cost';
            app.ApplyButton = uibutton(app.Panel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.StartDateDatePicker_2 = uidatepicker(app.Panel);
            app.StartDateDatePicker_2.DisplayFormat = 'dd-MMM-uuuu';
            app.StartDateDatePicker_2.ValueChangedFcn = createCallbackFcn(app, @StartDateDatePicker_2ValueChanged, true);
            app.StartDateDatePicker_2Label = uilabel(app.Panel);
            app.StartDateDatePicker_2Label.Text = 'Start Date';
            app.EndDateDatePicker_2 = uidatepicker(app.Panel);
            app.EndDateDatePicker_2.DisplayFormat = 'dd-MMM-uuuu';
            app.EndDateDatePicker_2.ValueChangedFcn = createCallbackFcn(app, @EndDateDatePicker_2ValueChanged, true);
            app.EndDateDatePicker_2Label = uilabel(app.Panel);
            app.EndDateDatePicker_2Label.Text = 'End Date';
            app.StatisticsTextArea = uitextarea(app.Panel);
            app.StatisticsTextAreaLabel = uilabel(app.Panel);
            app.StatisticsTextAreaLabel.Text = 'Statistics';
            app.DisplayScaleDropDown = uidropdown(app.Panel);
            app.DisplayScaleDropDown.Items = {'Daily', 'Monthly'};
            app.DisplayScaleDropDown.Value = 'Daily';
            app.DisplayScaleDropDownLabel = uilabel(app.Panel);
            app.DisplayScaleDropDownLabel.Text = {'Display Scale'; ''};
            app.EnteryourInitalcapitalEditField = uieditfield(app.Panel, 'numeric');
            app.EnteryourInitalcapitalEditFieldLabel = uilabel(app.Panel);
            app.EnteryourInitalcapitalEditFieldLabel.Text = 'Enter your Inital capital';
            app.FinalCapitalEditFieldLabel = uilabel(app.Panel);
            app.FinalCapitalEditFieldLabel.Text = 'Final Capital';
            app.FinalCapitalEditField = uieditfield(app.Panel, 'numeric');
            app.LoadPortfolioButton = uibutton(app.Panel, 'push');
            app.LoadPortfolioButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPortfolioButtonPushed, true);
            app.LoadPortfolioButton.Text = 'Load Portfolio';
            app.ClearButton = uibutton(app.Panel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.RiskManagementTab = uitab(app.TabGroup);
            app.RiskManagementTab.Title = 'Risk Management';
            app.VaRandESBacktestingPanel = uipanel(app.RiskManagementTab);
            app.VaRandESBacktestingPanel.Title = 'VaR and ES Backtesting';
            app.Lamp = uilamp(app.VaRandESBacktestingPanel);
            app.PlotButton = uibutton(app.VaRandESBacktestingPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.StartDateDatePickerLabel = uilabel(app.VaRandESBacktestingPanel);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.VaRandESBacktestingPanel);
            app.StartDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @StartDateDatePickerValueChanged, true);
            app.EndDateDatePickerLabel = uilabel(app.VaRandESBacktestingPanel);
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.EndDateDatePicker = uidatepicker(app.VaRandESBacktestingPanel);
            app.EndDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @EndDateDatePickerValueChanged, true);
            app.VaRLevelEditFieldLabel = uilabel(app.VaRandESBacktestingPanel);
            app.VaRLevelEditFieldLabel.Text = 'VaR Level';
            app.VaRLevelEditField = uieditfield(app.VaRandESBacktestingPanel, 'numeric');
            app.UIAxes4 = uiaxes(app.VaRandESBacktestingPanel);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4_2 = uiaxes(app.VaRandESBacktestingPanel);
            title(app.UIAxes4_2, 'Title')
            xlabel(app.UIAxes4_2, 'X')
            ylabel(app.UIAxes4_2, 'Y')
            zlabel(app.UIAxes4_2, 'Z')
            app.RiskMatrixPanel = uipanel(app.RiskManagementTab);
            app.RiskMatrixPanel.Title = 'Risk Matrix';
            app.UpdateButton_2 = uibutton(app.RiskMatrixPanel, 'push');
            app.UpdateButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_2Pushed, true);
            app.UpdateButton_2.Text = 'Update';
            app.UITable3 = uitable(app.RiskMatrixPanel);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.RowName = {};
            app.UnderlyingScaleEditFieldLabel = uilabel(app.RiskMatrixPanel);
            app.UnderlyingScaleEditFieldLabel.Text = 'Underlying Scale';
            app.UnderlyingScaleEditField = uieditfield(app.RiskMatrixPanel, 'text');
            app.VolScaleEditFieldLabel = uilabel(app.RiskMatrixPanel);
            app.VolScaleEditFieldLabel.Text = 'Vol Scale';
            app.VolScaleEditField = uieditfield(app.RiskMatrixPanel, 'text');
            app.RiskMetricsListBoxLabel = uilabel(app.RiskMatrixPanel);
            app.RiskMetricsListBoxLabel.Text = 'Risk Metrics';
            app.RiskMetricsListBox = uilistbox(app.RiskMatrixPanel);
            app.RiskMetricsListBox.Items = {'PNL', 'Delta', 'Gamma', 'Theta', 'Vega'};
            app.RiskMetricsListBox.Multiselect = 'on';
            app.RiskMetricsListBox.Value = {'PNL'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PRMIA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end